classdef app_model < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        RuntheParameterButton  matlab.ui.control.Button
        SimulateButton         matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: SimulateButton, UIAxes
        function SimulateButtonPushed(app, event)
            % Load the Simulink model
            load_system('simulink_model');  % Replace with your actual Simulink model name

            % Simulate the model
            simOut = sim("simulink_model.slx");

            % Access the simulation data
            Height = CPos.data(:,1);
            Latitude = CPos.data(:,2);
            Longitude = CPos.data(:,3);
            RangeTarget = RTar.data;
            DistanceObstacle = DObs.data;
            Time = time.data;
            
            % Plotting
            plot3(app.UIAxes, Longitude, Latitude, Height, 'LineWidth', 2);
            title(app.UIAxes, '3D Trajectory');
            xlabel(app.UIAxes, 'Longitude');
            ylabel(app.UIAxes, 'Latitude');
            zlabel(app.UIAxes, 'Height');
            grid(app.UIAxes, 'on');
        end

        % Button pushed function: RuntheParameterButton
        function RuntheParameterButtonPushed(app, event)
           %missile-model

clc; close all; clear all;
format short

% The state-space matrices for the missiles's longitudinal dynamics.
% Calculated from one of the missile from Raytheon missilese and defense
% These matrices describe how the system evolves over time
A = [-1.064 1.000; 290.26 0.00];        %State Matrix
B = [-0.25; -331.40];                   %Input Matrix
C = [-123.34 0.00; 0.00 1.00];          %Output Matrix
D = [-13.51; 0];                        %Feedforward Matrix

% Defines names for the states, inputs and outputs of the system
states = {'AoA', 'q'};
inputs = {'\delta_c'};
outputs = {'Az','q'};

% Creates a state-space (ss) model (sys) using the matrices A, B, C, and D, and specifies names for states, inputs, and outputs.
sys = ss(A,B,C,D,'statename',states,...
    'inputname',inputs,...
    'outputname',outputs);

% our TF (control q)
% Converting the state-space model to transfer function
TFs = tf(sys);
TF = TFs(2,1);
disp(pole(TF));

% subplot(1,2,1)
% bode(TF)

%% inner loop design (control q) - R >> Q, K is not f(C) - NOISE FREE

% weight matrices on states and control for LQR controller
Q = [0.1 0; 0 0.1];
R = 0.5; 

% Computes the LQR gain matrix K that minimizes the cost function based on the specified weighting matrices
[K,S,e] = lqr(A,B,Q,R);
fprintf('eigenvalues A-BK\n'); %state feedback
disp(eig(A-B*K));
fprintf('eigenvalues A-BKC\n'); %output feedback (not a good idea)s
disp(eig(A-B*K*C));
fprintf('Feedback gain K'); %LQR gain 
disp(K)

%closed loop system
Acl = A-B*K;
Bcl = B;
syscl = ss(Acl,Bcl,C,D,'statename',states,...
    'inputname',inputs,...
    'outputname',outputs);
%our TF (control q)
TF = tf(syscl);
TFc = TF(2,1);
% subplot(1,2,2)
% step(TFc);

%% inner loop design (control q) - NOISE  - KALMAN COMMAND

%other
G = eye(2);
H = 0*eye(2);

%covariance noise matrices, process Q, measurement R
Qbar = diag(0.00015*ones(1,2));
Rbar  = diag(0.55*ones(1,2)); 

%this is your state space with disturbances
sys_n = ss(A,[B G],C,[D H]);
[kest,L,P] = kalman(sys_n,Qbar,Rbar,0);

%assess the stability
Aob = A-L*C;

fprintf('Observer Eigenvalues\n');
disp(eig(Aob));

%%process and measurement noise sample times
dT1 = 0.75;
dT2 = 0.25;

%% same as above with LQG command

% Qx = blkdiag(Q,R);
% Qw = blkdiag(Qbar,Rbar);
% 
% [Klqg,info] = lqg(sys,Qx,Qw);

%% missile parameters 

Vel = 1021.08; %m/s (3350ft/s)
m2f = 3.2811;

%target location
LAT_TARGET = 34.6588;
LON_TARGET = -118.769745;
ELEV_TARGET = 795; %m - MSL

%INITIAL LOCATION
LAT_INIT = 34.2329;
LON_INIT = -119.4573;
ELEV_INIT = 10000; %m p- MSL

%OBSTACLE MIDPOINT LOCATION
LAT_OBS = 34.61916;
LON_OBS = -118.8429;

%% distance calculator (init to target) (test with other example) - (t = 0)

R = 6371e3; %earth radius (m)
d2r = pi/180;

l1 = LAT_INIT*d2r;
u1 = LON_INIT *d2r;
l2 = LAT_TARGET*d2r;
u2 = LON_TARGET*d2r;

dl = l2-l1;
du = u2-u1;

a = sin(dl/2)^2 + cos(l1)*cos(l2)*sin(du/2)^2;

c = 2*atan2(sqrt(a),sqrt(1-a));

d = R*c; %horizontal distance (in m)

%inital range (k (how far from target)
r = sqrt(d^2+(ELEV_TARGET-ELEV_INIT)^2);

%% azimuth (yaw) calculator (t = 0), stays constant

yaw_init = azimuth(LAT_INIT,LON_INIT,LAT_TARGET,LON_TARGET);
yaw = yaw_init*d2r;

%% initial flight path angle

dh = abs(ELEV_TARGET-ELEV_INIT);
FPA_INIT = atan(dh/d); %rad


% Save variables to the base workspace
assignin('base', 'A', A);
assignin('base', 'B', B);
assignin('base', 'C', C);
assignin('base', 'D', D);
assignin('base', 'states', states);
assignin('base', 'inputs', inputs);
assignin('base', 'outputs', outputs);
assignin('base', 'sys', sys);
assignin('base', 'TFs', TFs);
assignin('base', 'TF', TF);
assignin('base', 'Q', Q);
assignin('base', 'R', R);
assignin('base', 'K', K);
assignin('base', 'Acl', Acl);
assignin('base', 'Bcl', Bcl);
assignin('base', 'syscl', syscl);
assignin('base', 'TFc', TFc);
assignin('base', 'G', G);
assignin('base', 'H', H);
assignin('base', 'Qbar', Qbar);
assignin('base', 'Rbar', Rbar);
assignin('base', 'sys_n', sys_n);
assignin('base', 'kest', kest);
assignin('base', 'L', L);
assignin('base', 'P', P);
assignin('base', 'dT1', dT1);
assignin('base', 'dT2', dT2);
assignin('base', 'Vel', Vel);
assignin('base', 'm2f', m2f);
assignin('base', 'LAT_TARGET', LAT_TARGET);
assignin('base', 'LON_TARGET', LON_TARGET);
assignin('base', 'ELEV_TARGET', ELEV_TARGET);
assignin('base', 'LAT_INIT', LAT_INIT);
assignin('base', 'LON_INIT', LON_INIT);
assignin('base', 'ELEV_INIT', ELEV_INIT);
assignin('base', 'LAT_OBS', LAT_OBS);
assignin('base', 'LON_OBS', LON_OBS);
assignin('base', 'd2r', d2r);
assignin('base', 'R', R);
assignin('base', 'd', d);
assignin('base', 'r', r);
assignin('base', 'yaw_init', yaw_init);
assignin('base', 'yaw', yaw);
assignin('base', 'dh', dh);
assignin('base', 'FPA_INIT', FPA_INIT);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.RuntheParameterButton = uibutton(app.UIFigure, 'push');
            app.RuntheParameterButton.ButtonPushedFcn = createCallbackFcn(app, @RuntheParameterButtonPushed, true);
            app.RuntheParameterButton.Text = 'Run the Parameter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_model

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end