classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout2                    matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        DTrajectoryTab                 matlab.ui.container.Tab
        Trajectory3DAxes               matlab.ui.control.UIAxes
        HeightTab                      matlab.ui.container.Tab
        HeightAxes                     matlab.ui.control.UIAxes
        SpeedTab                       matlab.ui.container.Tab
        SpeedAxes                      matlab.ui.control.UIAxes
        AccelerationTab                matlab.ui.container.Tab
        AccelerationAxes               matlab.ui.control.UIAxes
        AdvanceSettingTab              matlab.ui.container.Tab
        LocationDefaultButton          matlab.ui.control.Button
        OLonEditField                  matlab.ui.control.NumericEditField
        OLonEditFieldLabel             matlab.ui.control.Label
        OLatEditField                  matlab.ui.control.NumericEditField
        OLatEditFieldLabel             matlab.ui.control.Label
        THeightEditField               matlab.ui.control.NumericEditField
        THeightEditFieldLabel          matlab.ui.control.Label
        TLonEditField                  matlab.ui.control.NumericEditField
        TLonEditFieldLabel             matlab.ui.control.Label
        TLatEditField                  matlab.ui.control.NumericEditField
        TLatEditFieldLabel             matlab.ui.control.Label
        MHeightEditField               matlab.ui.control.NumericEditField
        MHeightEditFieldLabel          matlab.ui.control.Label
        MLonEditField                  matlab.ui.control.NumericEditField
        MLonEditFieldLabel             matlab.ui.control.Label
        MLatEditField                  matlab.ui.control.NumericEditField
        MLatEditFieldLabel             matlab.ui.control.Label
        GridLayout3                    matlab.ui.container.GridLayout
        GridLayout8                    matlab.ui.container.GridLayout
        AxialAccelerationms2EditField  matlab.ui.control.NumericEditField
        AxialAccelerationms2EditFieldLabel  matlab.ui.control.Label
        GridLayout7                    matlab.ui.container.GridLayout
        InitialVelocitymsEditField     matlab.ui.control.NumericEditField
        InitialVelocitymsEditFieldLabel  matlab.ui.control.Label
        GridLayout6                    matlab.ui.container.GridLayout
        TotalMissileMasskgEditField    matlab.ui.control.NumericEditField
        TotalMissileMasskgEditFieldLabel  matlab.ui.control.Label
        GridLayout4                    matlab.ui.container.GridLayout
        SimButton                      matlab.ui.control.Button
        DefaultButton                  matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        GridLayout5                    matlab.ui.container.GridLayout
        ReadmePanel                    matlab.ui.container.Panel
        ResetButton                    matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        GuidedMissileSimulationLabel   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DefaultButton
        function MissileParamDefault(app, event)
            % Setting the Parameter to the default Value
            app.AxialAccelerationms2EditField.Value = -60;
            app.InitialVelocitymsEditField.Value = 1021.08;
            app.TotalMissileMasskgEditField.Value = 162;
        end

        % Button pushed function: SimButton
        function StartSimulation(app, event)
 %% Calculate the Missile model
 
            clc 
            fprintf("Simulating.............. ");
            fprintf("\n")

            % Let the User only set the Total mass, Initial Velocity and Axial Acceleration 
            In_v0 = abs(app.InitialVelocitymsEditField.Value);       %m/sec
            In_m = abs(app.TotalMissileMasskgEditField.Value);      %kg
            In_Ax0 = -1*abs(app.AxialAccelerationms2EditField.Value); %m/s^2

            % Change the Input unit
            v0 = In_v0 * 3.2808399; %ft/sec
            m = In_m * 0.0685217659; %slug
            Ax0 = In_Ax0 * 3.2808399; %ft/sec^2
            
            % Set the constant
            Iyy = 137.8;        %Pitch Moment of Inertia (slug*ft^2)
            xbar = 1.2;         %Distance from CG to IMU Positive Forward (ft)
            Cfaoa = -5.5313;    %Pitch Force Coefficient due to Angle of Attack (no unit)
            Cmaoa = 6.6013;     %Pitch Moment Coefficient due to Angle of Attack (no unit)
            Cffd = -1.2713;     %Pitch Force Coefficient due to Fin Deflection (no unit)
            Cmfd = -7.5368;     %Pitch Moment Coefficuent due to Fin Deflection (no unt)
            Qbar = 13332;       %Dynamic Pressure (lb/ft^2)
            % We also se the dimension of the missile to be constant
            s = 0.5454;         %Reference Area (ft^2)
            d = 0.8333;         %Reference Length (ft)
            g = 32.174;         %Gravity Constant (ft/sec^2)
            
            %Calculate the state Matrix
            A11 = (1/v0) * ( (Qbar*s*Cfaoa)/m - Ax0 );
            A21 = (Qbar*s*d*Cmaoa) / Iyy;
            A = [A11 1 ; A21 0];
            
            %Calculate the Input Matrix
            B11 = (Qbar*s*Cffd) / (m*v0);
            B21 = (Qbar*s*d*Cmfd) / Iyy;
            B = [B11 ; B21];
            
            %Calculate the Output Matrix
            C11 = (Qbar*s*Cfaoa)/(m*g) - (Qbar*s*d*Cmaoa*xbar)/(g*Iyy);
            C = [C11 0 ; 0 1];
            
            %Calculate the Feedforward Matrix
            D11 = (Qbar*s*Cffd)/(m*g) - (Qbar*s*d*Cmfd*xbar)/(g*Iyy);
            D = [D11 ; 0];
            
            % Defines names for the states, inputs and outputs of the system
            states = {'AoA', 'q'};
            inputs = {'\delta_c'};
            outputs = {'Az','q'};
            
            % Creates a state-space (ss) model (sys) using the matrices A, B, C, and D, and specifies names for states, inputs, and outputs.
            sys = ss(A,B,C,D,'statename',states,...
                'inputname',inputs,...
                'outputname',outputs);
            
            % our TF (control q)
            % Converting the state-space model to transfer function
            TFs = tf(sys);
            TF = TFs(2,1);
            
%% inner loop design (control q) - R >> Q, K is not f(C) - NOISE FREE
            
            % weight matrices on states and control for LQR controller
            Q = [0.1 0; 0 0.1];
            R = 0.5; 
            
            % Computes the LQR gain matrix K that minimizes the cost function based on the specified weighting matrices
            [K,S,e] = lqr(A,B,Q,R);
            
            %closed loop system
            Acl = A-B*K;
            Bcl = B;
            syscl = ss(Acl,Bcl,C,D,'statename',states,...
                'inputname',inputs,...
                'outputname',outputs);
            %our TF (control q)
            TF = tf(syscl);
            TFc = TF(2,1);
            
%% inner loop design (control q) - NOISE  - KALMAN COMMAND
            
            %other
            G = eye(2);
            H = 0*eye(2);
            
            %covariance noise matrices, process Q, measurement R
            Qbar = diag(0.00015*ones(1,2));
            Rbar  = diag(0.55*ones(1,2)); 
            
            %this is your state space with disturbances
            sys_n = ss(A,[B G],C,[D H]);
            [kest,L,P] = kalman(sys_n,Qbar,Rbar,0);
            
            %assess the stability
            Aob = A-L*C;
            
            %%process and measurement noise sample times
            dT1 = 0.75;
            dT2 = 0.25;
            
%% same as above with LQG command
            
            % Qx = blkdiag(Q,R);
            % Qw = blkdiag(Qbar,Rbar);
            % 
            % [Klqg,info] = lqg(sys,Qx,Qw);
            
%% missile parameters 
            
            Vel = In_v0; %Initial Velocity of the missile (m/s)
            m2f = 3.2811;
            
            %target location
            LAT_TARGET = app.TLatEditField.Value;
            LON_TARGET = app.TLonEditField.Value;
            ELEV_TARGET = app.THeightEditField.Value; %m - MSL
            
            %INITIAL LOCATION
            LAT_INIT = app.MLatEditField.Value;
            LON_INIT = app.MLonEditField.Value;
            ELEV_INIT = app.MHeightEditField.Value; %m p- MSL
            
            %OBSTACLE MIDPOINT LOCATION
            LAT_OBS = app.OLatEditField.Value;
            LON_OBS = app.OLonEditField.Value;
            
 %% distance calculator (init to target) (test with other example) - (t = 0)
            
            R = 6371e3; %earth radius (m)
            d2r = pi/180;
            
            l1 = LAT_INIT*d2r;
            u1 = LON_INIT *d2r;
            l2 = LAT_TARGET*d2r;
            u2 = LON_TARGET*d2r;
            
            dl = l2-l1;
            du = u2-u1;
            
            a = sin(dl/2)^2 + cos(l1)*cos(l2)*sin(du/2)^2;
            
            c = 2*atan2(sqrt(a),sqrt(1-a));
            
            d = R*c; %horizontal distance (in m)
            
            %inital range (k (how far from target)
            r = sqrt(d^2+(ELEV_TARGET-ELEV_INIT)^2);
            
%% azimuth (yaw) calculator (t = 0), stays constant
            
            yaw_init = azimuth(LAT_INIT,LON_INIT,LAT_TARGET,LON_TARGET);
            yaw = yaw_init*d2r;
            
%% initial flight path angle
            
            dh = abs(ELEV_TARGET-ELEV_INIT);
            FPA_INIT = atan(dh/d); %rad
            
            
%% Save the Variable to the base workspace
            
            assignin('base', 'A', A);
            assignin('base', 'B', B);
            assignin('base', 'C', C);
            assignin('base', 'D', D);
            assignin('base', 'states', states);
            assignin('base', 'inputs', inputs);
            assignin('base', 'outputs', outputs);
            assignin('base', 'sys', sys);
            assignin('base', 'TFs', TFs);
            assignin('base', 'TF', TF);
            assignin('base', 'Q', Q);
            assignin('base', 'R', R);
            assignin('base', 'K', K);
            assignin('base', 'Acl', Acl);
            assignin('base', 'Bcl', Bcl);
            assignin('base', 'syscl', syscl);
            assignin('base', 'TFc', TFc);
            assignin('base', 'G', G);
            assignin('base', 'H', H);
            assignin('base', 'Qbar', Qbar);
            assignin('base', 'Rbar', Rbar);
            assignin('base', 'sys_n', sys_n);
            assignin('base', 'kest', kest);
            assignin('base', 'L', L);
            assignin('base', 'P', P);
            assignin('base', 'dT1', dT1);
            assignin('base', 'dT2', dT2);
            assignin('base', 'Vel', Vel);
            assignin('base', 'm2f', m2f);
            assignin('base', 'LAT_TARGET', LAT_TARGET);
            assignin('base', 'LON_TARGET', LON_TARGET);
            assignin('base', 'ELEV_TARGET', ELEV_TARGET);
            assignin('base', 'LAT_INIT', LAT_INIT);
            assignin('base', 'LON_INIT', LON_INIT);
            assignin('base', 'ELEV_INIT', ELEV_INIT);
            assignin('base', 'LAT_OBS', LAT_OBS);
            assignin('base', 'LON_OBS', LON_OBS);
            assignin('base', 'd2r', d2r);
            assignin('base', 'R', R);
            assignin('base', 'd', d);
            assignin('base', 'r', r);
            assignin('base', 'yaw_init', yaw_init);
            assignin('base', 'yaw', yaw);
            assignin('base', 'dh', dh);
            assignin('base', 'FPA_INIT', FPA_INIT);


%% Simulate the function

            % Load the Simulink model
            load_system('simulink_main');  % Replace with your actual Simulink model name

            % Simulate the model
            % Set the maximum simulation time (2 minutes)
            maxSimulationTime = 2*60;  % 2 minutes in seconds
            
            % Simulate the model with a maximum simulation time
            try
                sim('simulink_main.slx');

                % Access the simulation data
                Height = CPos.data(:,1);
                Latitude = CPos.data(:,2);
                Longitude = CPos.data(:,3);
                Time = time.data;
                
                % 3D trajectory
                plot3(app.Trajectory3DAxes, Longitude, Latitude, Height, 'LineWidth', 2);
                title(app.Trajectory3DAxes, '3D Trajectory');
                xlabel(app.Trajectory3DAxes, 'Longitude');
                ylabel(app.Trajectory3DAxes, 'Latitude');
                zlabel(app.Trajectory3DAxes, 'Height');
                grid(app.Trajectory3DAxes, 'on');
    
                % Calculate ZVelocity
                delta_h = diff(Height);
                delta_t = diff(Time);
                Zvelocity = delta_h ./ delta_t;
    
                % Calculate distance between consecutive latitude and longitude points
                distance = zeros(size(Latitude));
                for i = 2:length(Latitude)
                    lat1 = Latitude(i-1);
                    lon1 = Longitude(i-1);
                    lat2 = Latitude(i);
                lon2 = Longitude(i);
        
                % Haversine formula to calculate distance between two points on a sphere
                dlat = deg2rad(lat2 - lat1);
                dlon = deg2rad(lon2 - lon1);
                a = sin(dlat/2)^2 + cos(deg2rad(lat1)) * cos(deg2rad(lat2)) * sin(dlon/2)^2;
                c = 2 * atan2(sqrt(a), sqrt(1-a));
                distance(i) = R * c;
                end
                
                % Calculate Ground speed
                Gspeed = abs(distance(2:end) ./ delta_t);
    
                % Calculate Missile speed
                Mspeed = sqrt(Gspeed.^2 + Zvelocity.^2);
    
                % Calculate Acceleration
                delta_v = diff(Mspeed);
                Acceleration = delta_v ./ delta_t(1:end-1);
    
                % Plot Height versus Time
                plot(app.HeightAxes, Time, Height, 'LineWidth', 2);
                title(app.HeightAxes, 'Height');
                xlabel(app.HeightAxes, 'Time');
                ylabel(app.HeightAxes, 'Height');
                grid(app.HeightAxes, 'on');
    
                % Plot Velocity versus Time
                plot(app.SpeedAxes, Time(1:end-1), Mspeed, 'LineWidth', 2);
                title(app.SpeedAxes, 'Speed');
                xlabel(app.SpeedAxes, 'Time');
                ylabel(app.SpeedAxes, 'Speed');
                grid(app.SpeedAxes, 'on');
    
                % Plot Acceleration versus Time
                plot(app.AccelerationAxes, Time(2:end-1), Acceleration, 'LineWidth', 2);
                title(app.AccelerationAxes, 'Acceleration');
                xlabel(app.AccelerationAxes, 'Time');
                ylabel(app.AccelerationAxes, 'Acceleration');
                grid(app.AccelerationAxes, 'on');
    
                clc
                fprintf("Simulation Success!!")
                fprintf("\n")
                fprintf("Time: %s ",Time(end))
                fprintf("\n")
                fprintf("Final speed: %s",Mspeed(end))
                fprintf("\n")
                fprintf("Github: https://github.com/SandKorrawach/ScriProgProject.git ")
            catch
                clc
                fprintf('Simulation terminated due to exceeding the maximum time limit.');
                fprintf("\n");
            end


        end

        % Button pushed function: ResetButton
        function StopSimulation(app, event)
          clc
          fprintf("Reset.........")
          fprintf("\n")
          % Stop Simulink Model
         try
            stop(sim("simulink_main.slx"))
            fprintf("Reset success!!")
         catch
            fprintf('Error stopping Simulink model.');
         end
          fprintf("\n")
        end

        % Button pushed function: LocationDefaultButton
        function LocationDefautl(app, event)
            app.MLatEditField.Value = 34.2329;
            app.MLonEditField.Value = -119.4573;
            app.MHeightEditField.Value = 10000;
            app.TLatEditField.Value = 34.6588;
            app.TLonEditField.Value = -118.769745;
            app.THeightEditField.Value = 795;
            app.OLatEditField.Value = 34.61916;
            app.OLonEditField.Value = -118.8429;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GuidedMissileSimulationLabel = uilabel(app.GridLayout);
            app.GuidedMissileSimulationLabel.Text = 'Guided Missile Simulation';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.ReadmePanel = uipanel(app.GridLayout5);
            app.ReadmePanel.Title = 'Readme';
            app.Label = uilabel(app.ReadmePanel);
            app.Label.Text = {'This simulation is designed to replicate the behavior of guided'; 'missile. For the default settingThe current postion, obstacle '; 'position, and target position have already been set. The GNC '; 'system are programmed to navigate the missile, ensuring it '; 'passes through the obstacle without collision'; ''; ''};
            app.ResetButton = uibutton(app.ReadmePanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @StopSimulation, true);
            app.ResetButton.Text = 'Reset';
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Document', 'simulink_model.png');
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.DefaultButton = uibutton(app.GridLayout4, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @MissileParamDefault, true);
            app.DefaultButton.Text = 'Default';
            app.SimButton = uibutton(app.GridLayout4, 'push');
            app.SimButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulation, true);
            app.SimButton.Text = 'Sim';
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.TotalMissileMasskgEditFieldLabel = uilabel(app.GridLayout6);
            app.TotalMissileMasskgEditFieldLabel.Text = 'Total Missile Mass (kg)';
            app.TotalMissileMasskgEditField = uieditfield(app.GridLayout6, 'numeric');
            app.TotalMissileMasskgEditField.Value = 162;
            app.GridLayout7 = uigridlayout(app.GridLayout3);
            app.InitialVelocitymsEditFieldLabel = uilabel(app.GridLayout7);
            app.InitialVelocitymsEditFieldLabel.Text = 'Initial Velocity (m/s)';
            app.InitialVelocitymsEditField = uieditfield(app.GridLayout7, 'numeric');
            app.InitialVelocitymsEditField.Value = 1021.08;
            app.GridLayout8 = uigridlayout(app.GridLayout3);
            app.AxialAccelerationms2EditFieldLabel = uilabel(app.GridLayout8);
            app.AxialAccelerationms2EditFieldLabel.Text = 'Axial Acceleration (m/s^2)';
            app.AxialAccelerationms2EditField = uieditfield(app.GridLayout8, 'numeric');
            app.AxialAccelerationms2EditField.Value = -60;
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.DTrajectoryTab = uitab(app.TabGroup);
            app.DTrajectoryTab.Title = '3D Trajectory ';
            app.Trajectory3DAxes = uiaxes(app.DTrajectoryTab);
            title(app.Trajectory3DAxes, 'Title')
            xlabel(app.Trajectory3DAxes, 'X')
            ylabel(app.Trajectory3DAxes, 'Y')
            zlabel(app.Trajectory3DAxes, 'Z')
            app.HeightTab = uitab(app.TabGroup);
            app.HeightTab.Title = 'Height';
            app.HeightAxes = uiaxes(app.HeightTab);
            title(app.HeightAxes, 'Title')
            xlabel(app.HeightAxes, 'X')
            ylabel(app.HeightAxes, 'Y')
            zlabel(app.HeightAxes, 'Z')
            app.SpeedTab = uitab(app.TabGroup);
            app.SpeedTab.Title = 'Speed';
            app.SpeedAxes = uiaxes(app.SpeedTab);
            title(app.SpeedAxes, 'Title')
            xlabel(app.SpeedAxes, 'X')
            ylabel(app.SpeedAxes, 'Y')
            zlabel(app.SpeedAxes, 'Z')
            app.AccelerationTab = uitab(app.TabGroup);
            app.AccelerationTab.Title = 'Acceleration';
            app.AccelerationAxes = uiaxes(app.AccelerationTab);
            title(app.AccelerationAxes, 'Title')
            xlabel(app.AccelerationAxes, 'X')
            ylabel(app.AccelerationAxes, 'Y')
            zlabel(app.AccelerationAxes, 'Z')
            app.AdvanceSettingTab = uitab(app.TabGroup);
            app.AdvanceSettingTab.Title = 'Advance Setting';
            app.MLatEditFieldLabel = uilabel(app.AdvanceSettingTab);
            app.MLatEditFieldLabel.Text = 'MLat';
            app.MLatEditField = uieditfield(app.AdvanceSettingTab, 'numeric');
            app.MLatEditField.Value = 34.2329;
            app.MLonEditFieldLabel = uilabel(app.AdvanceSettingTab);
            app.MLonEditFieldLabel.Text = 'MLon';
            app.MLonEditField = uieditfield(app.AdvanceSettingTab, 'numeric');
            app.MLonEditField.Value = -119.4573;
            app.MHeightEditFieldLabel = uilabel(app.AdvanceSettingTab);
            app.MHeightEditFieldLabel.Text = 'MHeight';
            app.MHeightEditField = uieditfield(app.AdvanceSettingTab, 'numeric');
            app.MHeightEditField.Value = 10000;
            app.TLatEditFieldLabel = uilabel(app.AdvanceSettingTab);
            app.TLatEditFieldLabel.Text = 'TLat';
            app.TLatEditField = uieditfield(app.AdvanceSettingTab, 'numeric');
            app.TLatEditField.Value = 34.6588;
            app.TLonEditFieldLabel = uilabel(app.AdvanceSettingTab);
            app.TLonEditFieldLabel.Text = 'TLon';
            app.TLonEditField = uieditfield(app.AdvanceSettingTab, 'numeric');
            app.TLonEditField.Value = -118.769745;
            app.THeightEditFieldLabel = uilabel(app.AdvanceSettingTab);
            app.THeightEditFieldLabel.Text = 'THeight';
            app.THeightEditField = uieditfield(app.AdvanceSettingTab, 'numeric');
            app.THeightEditField.Value = 795;
            app.OLatEditFieldLabel = uilabel(app.AdvanceSettingTab);
            app.OLatEditFieldLabel.Text = 'OLat';
            app.OLatEditField = uieditfield(app.AdvanceSettingTab, 'numeric');
            app.OLatEditField.Value = 34.61916;
            app.OLonEditFieldLabel = uilabel(app.AdvanceSettingTab);
            app.OLonEditFieldLabel.Text = 'OLon';
            app.OLonEditField = uieditfield(app.AdvanceSettingTab, 'numeric');
            app.OLonEditField.Value = -118.8429;
            app.LocationDefaultButton = uibutton(app.AdvanceSettingTab, 'push');
            app.LocationDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @LocationDefautl, true);
            app.LocationDefaultButton.Text = 'Location Default';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end